classdef appRVC_App_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1          matlab.ui.Figure
        RTBplot          matlab.ui.control.UIAxes
        tableDH          matlab.ui.control.Table
        name             matlab.ui.control.Label
        text3            matlab.ui.control.Label
        uipanel2         matlab.ui.container.Panel
        buttonCD         matlab.ui.control.Button
        buttonCI         matlab.ui.control.Button
        popupmenuCI      matlab.ui.control.DropDown
        uipanel3         matlab.ui.container.Panel
        q1               matlab.ui.control.EditField
        sliderQ1         matlab.ui.control.Slider
        sliderQ2         matlab.ui.control.Slider
        sliderQ3         matlab.ui.control.Slider
        sliderQ4         matlab.ui.control.Slider
        sliderQ5         matlab.ui.control.Slider
        sliderQ6         matlab.ui.control.Slider
        sliderQ7         matlab.ui.control.Slider
        q2               matlab.ui.control.EditField
        q3               matlab.ui.control.EditField
        q4               matlab.ui.control.EditField
        q5               matlab.ui.control.EditField
        q6               matlab.ui.control.EditField
        q7               matlab.ui.control.EditField
        text11           matlab.ui.control.Label
        uipanel4         matlab.ui.container.Panel
        text6            matlab.ui.control.Label
        tableT           matlab.ui.control.Table
        textTransl       matlab.ui.control.Label
        editX            matlab.ui.control.EditField
        pushPlusX        matlab.ui.control.Button
        pushMinusX       matlab.ui.control.Button
        editY            matlab.ui.control.EditField
        pushPlusY        matlab.ui.control.Button
        pushMinusY       matlab.ui.control.Button
        editZ            matlab.ui.control.EditField
        pushMinusZ       matlab.ui.control.Button
        pushPlusZ        matlab.ui.control.Button
        textRot          matlab.ui.control.Label
        editPitch        matlab.ui.control.EditField
        pushbutton18     matlab.ui.control.Button
        pushbutton19     matlab.ui.control.Button
        pushbutton17     matlab.ui.control.Button
        editRoll         matlab.ui.control.EditField
        pushbutton16     matlab.ui.control.Button
        pushbutton21     matlab.ui.control.Button
        editYaw          matlab.ui.control.EditField
        pushbutton20     matlab.ui.control.Button
        buttonReset      matlab.ui.control.Button
        popupSol         matlab.ui.control.DropDown
        uipanel5         matlab.ui.container.Panel
        editCond         matlab.ui.control.EditField
        textCond         matlab.ui.control.Label
        editManip        matlab.ui.control.EditField
        textManip        matlab.ui.control.Label
        editRankJ        matlab.ui.control.EditField
        textRankJ        matlab.ui.control.Label
        checkEllipseV    matlab.ui.control.CheckBox
        checkEllipseW    matlab.ui.control.CheckBox
        textJsingu       matlab.ui.control.Label
        textJsinguValue  matlab.ui.control.Label
        buttonWorkspace  matlab.ui.control.Button
    end

    
    methods (Access = private)
        function editJsingu_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editJsingu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function listbox2_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to listbox2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function listbox3_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to listbox3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Load data for component configuration
            componentData = load('appRVC_App_2.mat');
            
            % Set component properties that require runtime configuration
            app.RTBplot.UserData = componentData.RTBplot.UserData;
            app.tableDH.BackgroundColor = [1 1 1;0.9412 0.9412 0.9412];
            app.tableDH.ColumnFormat = {[] [] [] [] []};
            app.tableDH.Data = componentData.tableDH.Data;
            app.tableT.BackgroundColor = [1 1 1;0.9412 0.9412 0.9412];
            app.tableT.ColumnFormat = {[] [] [] []};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function appRVC_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to appRVC (see VARARGIN)
            
            % Choose default command line output for appRVC
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % UIWAIT makes appRVC wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            global R q;
            R = LBRiiwa();
            q = [0 0 0 0 0 0 0];
            updateDataQ(handles);
            updateJacobian(handles);
            updatePlot(handles);
        end

        % Button pushed function: buttonCD
        function buttonCD_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to buttonCD (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global R q T;
            T = R.fkine(q);
            updateDataT(handles);
            updateJacobian(handles);
        end

        % Button pushed function: buttonCI
        function buttonCI_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to buttonCI (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global R q T stackSol;
            if (get(handles.popupmenuCI, 'Value') == 1)
                q = R.ikine(T);
                R.plot(q);
            elseif (get(handles.popupmenuCI, 'Value') == 2)
                aux = T.tr2rpy;
                disp([T.t(1) T.t(2) T.t(3) aux(1) aux(2) aux(3)])
                %q_lst = iiwa_inverse(q(3), [T.t(1) T.t(2) T.t(3)], [aux(1) aux(2) aux(3)], R);
                stackSol = iiwa_inverse(q(3), T.T, R);
                q = stackSol(:, 1)';
                view(3)
                R.plot(q);
            %     set(handles.listSolutionCI, 'String', ...
            %         {"1", "2", "3", "4", "5", "6", "7", "8"});
                set(handles.popupSol, 'String', ...
                        ["1", "2", "3", "4", "5", "6", "7", "8"]);
            
            end
            updateDataQ(handles);
        end

        % Button pushed function: buttonReset
        function buttonReset_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to buttonReset (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global q;
            q = [0 0 0 0 0 0 0];
            updateDataQ(handles);
            updatePlot(handles);
        end

        % Button pushed function: buttonWorkspace
        function buttonWorkspace_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to buttonWorkspace (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global R q;
            load('workspace', 'wo');
            R.plot(wo, 'trail', {'r', 'LineWidth', 2});
            q = wo(length(wo(:, 1)), :);
            updateDataQ(handles);
        end

        % Value changed function: checkEllipseV
        function checkEllipseV_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkEllipseV (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkEllipseV
            global flag_ellipseV flag_ellipseW;
            flag_ellipseV = get(hObject, 'Value');
            if flag_ellipseV
                flag_ellipseW = 0;
                set(handles.checkEllipseW, 'Value', 0);
                updatePlot(handles);
            end
        end

        % Value changed function: checkEllipseW
        function checkEllipseW_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkEllipseW (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkEllipseW
            global flag_ellipseV flag_ellipseW;
            flag_ellipseW = get(hObject, 'Value');
            if flag_ellipseW
                flag_ellipseV = 0;
                set(handles.checkEllipseV, 'Value', 0);
                updatePlot(handles);
            end
        end

        % Value changed function: editX
        function editX_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editX (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editX as text
            %        str2double(get(hObject,'String')) returns contents of editX as a double
            global T;
            T.t(1) = str2double(get(hObject, 'String'));
            updateDataT(handles);
        end

        % Value changed function: editY
        function editY_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editY (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editY as text
            %        str2double(get(hObject,'String')) returns contents of editY as a double
            global T;
            T.t(2) = str2double(get(hObject, 'String'));
            updateDataT(handles);
        end

        % Value changed function: editZ
        function editZ_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editZ (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editZ as text
            %        str2double(get(hObject,'String')) returns contents of editZ as a double
            global T;
            T.t(3) = str2double(get(hObject, 'String'));
            updateDataT(handles);
        end

        % Value changed function: popupSol
        function popupSol_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupSol (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupSol contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupSol
            global R q stackSol;
            index = get(hObject, 'Value');
            q = stackSol(:, index)';
            view(3)
            R.plot(q);
            updateDataQ(handles);
        end

        % Value changed function: popupmenuCI
        function popupmenuCI_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenuCI (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenuCI contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenuCI
            global flag_ci;
            flag_ci = get(hObject, 'Value');
        end

        % Value changed function: q1
        function q1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to q1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of q1 as text
            %        str2double(get(hObject,'String')) returns contents of q1 as a double
            global q;
            aux = str2double(get(hObject, 'String'));
            set(handles.sliderQ1, 'Value', aux);
            q(1) = deg2rad(aux);
            updatePlot(handles);
        end

        % Value changed function: q2
        function q2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to q2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of q2 as text
            %        str2double(get(hObject,'String')) returns contents of q2 as a double
            global q;
            aux = str2double(get(hObject, 'String'));
            set(handles.sliderQ2, 'Value', aux);
            q(2) = deg2rad(aux);
            updatePlot(handles);
        end

        % Value changed function: q3
        function q3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to q3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of q3 as text
            %        str2double(get(hObject,'String')) returns contents of q3 as a double
            global q;
            aux = str2double(get(hObject, 'String'));
            set(handles.sliderQ3, 'Value', aux);
            q(3) = deg2rad(aux);
            updatePlot(handles);
        end

        % Value changed function: q4
        function q4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to q4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of q4 as text
            %        str2double(get(hObject,'String')) returns contents of q4 as a double
            global q;
            aux = str2double(get(hObject, 'String'));
            set(handles.sliderQ4, 'Value', aux);
            q(4) = deg2rad(aux);
            updatePlot(handles);
        end

        % Value changed function: q5
        function q5_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to q5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of q5 as text
            %        str2double(get(hObject,'String')) returns contents of q5 as a double
            global q;
            aux = str2double(get(hObject, 'String'));
            set(handles.sliderQ5, 'Value', aux);
            q(5) = deg2rad(aux);
            updatePlot(handles);
        end

        % Value changed function: q6
        function q6_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to q6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of q6 as text
            %        str2double(get(hObject,'String')) returns contents of q6 as a double
            global q;
            aux = str2double(get(hObject, 'String'));
            set(handles.sliderQ6, 'Value', aux);
            q(6) = deg2rad(aux);
            updatePlot(handles);
        end

        % Value changed function: q7
        function q7_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to q7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of q7 as text
            %        str2double(get(hObject,'String')) returns contents of q7 as a double
            global q;
            aux = str2double(get(hObject, 'String'));
            set(handles.sliderQ7, 'Value', aux);
            q(7) = deg2rad(aux);
            updatePlot(handles);
        end

        % Value changed function: sliderQ1
        function sliderQ1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sliderQ1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            global q;
            aux = get(hObject, 'Value');
            set(handles.q1, 'String', num2str(aux));
            q(1) = deg2rad(aux);
            updateJacobian(handles);
            updatePlot(handles);
        end

        % Value changed function: sliderQ2
        function sliderQ2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sliderQ2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            global q;
            aux = get(hObject, 'Value');
            set(handles.q2, 'String', num2str(aux));
            q(2) = deg2rad(aux);
            updateJacobian(handles);
            updatePlot(handles);
        end

        % Value changed function: sliderQ3
        function sliderQ3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sliderQ3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            global q;
            aux = get(hObject, 'Value');
            set(handles.q3, 'String', num2str(aux));
            q(3) = deg2rad(aux);
            updateJacobian(handles);
            updatePlot(handles);
        end

        % Value changed function: sliderQ4
        function sliderQ4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sliderQ4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            global q;
            aux = get(hObject, 'Value');
            set(handles.q4, 'String', num2str(aux));
            q(4) = deg2rad(aux);
            updateJacobian(handles);
            updatePlot(handles);
        end

        % Value changed function: sliderQ5
        function sliderQ5_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sliderQ5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            global q;
            aux = get(hObject, 'Value');
            set(handles.q5, 'String', num2str(aux));
            q(5) = deg2rad(aux);
            updateJacobian(handles);
            updatePlot(handles);
        end

        % Value changed function: sliderQ6
        function sliderQ6_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sliderQ6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            global q;
            aux = get(hObject, 'Value');
            set(handles.q6, 'String', num2str(aux));
            q(6) = deg2rad(aux);
            updateJacobian(handles);
            updatePlot(handles);
        end

        % Value changed function: sliderQ7
        function sliderQ7_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sliderQ7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            global q;
            aux = get(hObject, 'Value');
            set(handles.q7, 'String', num2str(aux));
            q(7) = deg2rad(aux);
            updateJacobian(handles);
            updatePlot(handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'appRVC';
            app.figure1.Tag = 'figure1';
            app.RTBplot = uiaxes(app.figure1);
            app.RTBplot.XLim = [-1.2 1.2];
            app.RTBplot.YLim = [-1.2 1.2];
            app.RTBplot.ZLim = [-1.266 1.266];
            app.RTBplot.CLim = [-0.135 1];
            app.RTBplot.Tag = 'RTBplot';
            app.tableDH = uitable(app.figure1);
            app.tableDH.ColumnName = {'theta'; 'd'; 'a'; 'alpha'; 'offset'};
            app.tableDH.RowName = {'Link 1'; 'Link 2'; 'Link 3'; 'Link 4'; 'Link 5'; 'Link 6'; 'Link 7'};
            app.tableDH.ColumnEditable = [false false false false false];
            app.tableDH.Tag = 'tableDH';
            app.name = uilabel(app.figure1);
            app.name.Tag = 'name';
            app.name.Text = '协作机器人';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Denavit - Hartenberg Parameter';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Forward kinematics / Inverse kinematics';
            app.uipanel2.Tag = 'uipanel2';
            app.buttonCD = uibutton(app.uipanel2, 'push');
            app.buttonCD.ButtonPushedFcn = createCallbackFcn(app, @buttonCD_Callback, true);
            app.buttonCD.Tag = 'buttonCD';
            app.buttonCD.Text = 'Forward Solution';
            app.buttonCI = uibutton(app.uipanel2, 'push');
            app.buttonCI.ButtonPushedFcn = createCallbackFcn(app, @buttonCI_Callback, true);
            app.buttonCI.Tag = 'buttonCI';
            app.buttonCI.Text = 'Inverse Solution';
            app.popupmenuCI = uidropdown(app.uipanel2);
            app.popupmenuCI.Items = {'Peter Corke Toolbox ikine', 'Sol. Locking single joint q3 (igual a 0)'};
            app.popupmenuCI.ValueChangedFcn = createCallbackFcn(app, @popupmenuCI_Callback, true);
            app.popupmenuCI.Tag = 'popupmenuCI';
            app.popupmenuCI.Value = 'Sol. Locking single joint q3 (igual a 0)';
            app.uipanel3 = uipanel(app.uipanel2);
            app.uipanel3.Title = 'Joint Space';
            app.uipanel3.Tag = 'uipanel3';
            app.q1 = uieditfield(app.uipanel3, 'text');
            app.q1.ValueChangedFcn = createCallbackFcn(app, @q1_Callback, true);
            app.q1.Tag = 'q1';
            app.q1.Value = 'Q1';
            app.sliderQ1 = uislider(app.uipanel3);
            app.sliderQ1.Limits = [-170 170];
            app.sliderQ1.MajorTicks = [];
            app.sliderQ1.ValueChangedFcn = createCallbackFcn(app, @sliderQ1_Callback, true);
            app.sliderQ1.MinorTicks = [];
            app.sliderQ1.Tag = 'sliderQ1';
            app.sliderQ2 = uislider(app.uipanel3);
            app.sliderQ2.Limits = [-120 120];
            app.sliderQ2.MajorTicks = [];
            app.sliderQ2.ValueChangedFcn = createCallbackFcn(app, @sliderQ2_Callback, true);
            app.sliderQ2.MinorTicks = [];
            app.sliderQ2.Tag = 'sliderQ2';
            app.sliderQ3 = uislider(app.uipanel3);
            app.sliderQ3.Limits = [-170 170];
            app.sliderQ3.MajorTicks = [];
            app.sliderQ3.ValueChangedFcn = createCallbackFcn(app, @sliderQ3_Callback, true);
            app.sliderQ3.MinorTicks = [];
            app.sliderQ3.Tag = 'sliderQ3';
            app.sliderQ4 = uislider(app.uipanel3);
            app.sliderQ4.Limits = [-120 120];
            app.sliderQ4.MajorTicks = [];
            app.sliderQ4.ValueChangedFcn = createCallbackFcn(app, @sliderQ4_Callback, true);
            app.sliderQ4.MinorTicks = [];
            app.sliderQ4.Tag = 'sliderQ4';
            app.sliderQ5 = uislider(app.uipanel3);
            app.sliderQ5.Limits = [-170 170];
            app.sliderQ5.MajorTicks = [];
            app.sliderQ5.ValueChangedFcn = createCallbackFcn(app, @sliderQ5_Callback, true);
            app.sliderQ5.MinorTicks = [];
            app.sliderQ5.Tag = 'sliderQ5';
            app.sliderQ6 = uislider(app.uipanel3);
            app.sliderQ6.Limits = [-120 120];
            app.sliderQ6.MajorTicks = [];
            app.sliderQ6.ValueChangedFcn = createCallbackFcn(app, @sliderQ6_Callback, true);
            app.sliderQ6.MinorTicks = [];
            app.sliderQ6.Tag = 'sliderQ6';
            app.sliderQ7 = uislider(app.uipanel3);
            app.sliderQ7.Limits = [-175 175];
            app.sliderQ7.MajorTicks = [];
            app.sliderQ7.ValueChangedFcn = createCallbackFcn(app, @sliderQ7_Callback, true);
            app.sliderQ7.MinorTicks = [];
            app.sliderQ7.Tag = 'sliderQ7';
            app.q2 = uieditfield(app.uipanel3, 'text');
            app.q2.ValueChangedFcn = createCallbackFcn(app, @q2_Callback, true);
            app.q2.Tag = 'q2';
            app.q2.Value = 'Q2';
            app.q3 = uieditfield(app.uipanel3, 'text');
            app.q3.ValueChangedFcn = createCallbackFcn(app, @q3_Callback, true);
            app.q3.Tag = 'q3';
            app.q3.Value = 'Q3';
            app.q4 = uieditfield(app.uipanel3, 'text');
            app.q4.ValueChangedFcn = createCallbackFcn(app, @q4_Callback, true);
            app.q4.Tag = 'q4';
            app.q4.Value = 'Q4';
            app.q5 = uieditfield(app.uipanel3, 'text');
            app.q5.ValueChangedFcn = createCallbackFcn(app, @q5_Callback, true);
            app.q5.Tag = 'q5';
            app.q5.Value = 'Q5';
            app.q6 = uieditfield(app.uipanel3, 'text');
            app.q6.ValueChangedFcn = createCallbackFcn(app, @q6_Callback, true);
            app.q6.Tag = 'q6';
            app.q6.Value = 'Q6';
            app.q7 = uieditfield(app.uipanel3, 'text');
            app.q7.ValueChangedFcn = createCallbackFcn(app, @q7_Callback, true);
            app.q7.Tag = 'q7';
            app.q7.Value = 'Q7';
            app.text11 = uilabel(app.uipanel2);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Inverse Method:';
            app.uipanel4 = uipanel(app.uipanel2);
            app.uipanel4.Title = 'Cartesian Space';
            app.uipanel4.Tag = 'uipanel4';
            app.text6 = uilabel(app.uipanel4);
            app.text6.Tag = 'text6';
            app.text6.Text = 'T =';
            app.tableT = uitable(app.uipanel4);
            app.tableT.ColumnName = '';
            app.tableT.RowName = '';
            app.tableT.ColumnEditable = [true true true true];
            app.tableT.Tag = 'tableT';
            app.textTransl = uilabel(app.uipanel4);
            app.textTransl.Tag = 'textTransl';
            app.textTransl.Text = 'Position:';
            app.editX = uieditfield(app.uipanel4, 'text');
            app.editX.ValueChangedFcn = createCallbackFcn(app, @editX_Callback, true);
            app.editX.Tag = 'editX';
            app.editX.Value = 'X';
            app.pushPlusX = uibutton(app.uipanel4, 'push');
            app.pushPlusX.Tag = 'pushPlusX';
            app.pushPlusX.Text = '+';
            app.pushMinusX = uibutton(app.uipanel4, 'push');
            app.pushMinusX.Tag = 'pushMinusX';
            app.pushMinusX.Text = '-';
            app.editY = uieditfield(app.uipanel4, 'text');
            app.editY.ValueChangedFcn = createCallbackFcn(app, @editY_Callback, true);
            app.editY.Tag = 'editY';
            app.editY.Value = 'Y';
            app.pushPlusY = uibutton(app.uipanel4, 'push');
            app.pushPlusY.Tag = 'pushPlusY';
            app.pushPlusY.Text = '+';
            app.pushMinusY = uibutton(app.uipanel4, 'push');
            app.pushMinusY.Tag = 'pushMinusY';
            app.pushMinusY.Text = '-';
            app.editZ = uieditfield(app.uipanel4, 'text');
            app.editZ.ValueChangedFcn = createCallbackFcn(app, @editZ_Callback, true);
            app.editZ.Tag = 'editZ';
            app.editZ.Value = 'Z';
            app.pushMinusZ = uibutton(app.uipanel4, 'push');
            app.pushMinusZ.Tag = 'pushMinusZ';
            app.pushMinusZ.Text = '-';
            app.pushPlusZ = uibutton(app.uipanel4, 'push');
            app.pushPlusZ.Tag = 'pushPlusZ';
            app.pushPlusZ.Text = '+';
            app.textRot = uilabel(app.uipanel4);
            app.textRot.Tag = 'textRot';
            app.textRot.Text = 'Rotation:';
            app.editPitch = uieditfield(app.uipanel4, 'text');
            app.editPitch.Tag = 'editPitch';
            app.editPitch.Value = 'pitch';
            app.pushbutton18 = uibutton(app.uipanel4, 'push');
            app.pushbutton18.Tag = 'pushbutton18';
            app.pushbutton18.Text = '+';
            app.pushbutton19 = uibutton(app.uipanel4, 'push');
            app.pushbutton19.Tag = 'pushbutton19';
            app.pushbutton19.Text = '-';
            app.pushbutton17 = uibutton(app.uipanel4, 'push');
            app.pushbutton17.Tag = 'pushbutton17';
            app.pushbutton17.Text = '-';
            app.editRoll = uieditfield(app.uipanel4, 'text');
            app.editRoll.Tag = 'editRoll';
            app.editRoll.Value = 'roll';
            app.pushbutton16 = uibutton(app.uipanel4, 'push');
            app.pushbutton16.Tag = 'pushbutton16';
            app.pushbutton16.Text = '+';
            app.pushbutton21 = uibutton(app.uipanel4, 'push');
            app.pushbutton21.Tag = 'pushbutton21';
            app.pushbutton21.Text = '-';
            app.editYaw = uieditfield(app.uipanel4, 'text');
            app.editYaw.Tag = 'editYaw';
            app.editYaw.Value = 'yaw';
            app.pushbutton20 = uibutton(app.uipanel4, 'push');
            app.pushbutton20.Tag = 'pushbutton20';
            app.pushbutton20.Text = '+';
            app.buttonReset = uibutton(app.uipanel2, 'push');
            app.buttonReset.ButtonPushedFcn = createCallbackFcn(app, @buttonReset_Callback, true);
            app.buttonReset.Tag = 'buttonReset';
            app.buttonReset.Text = 'RESET';
            app.popupSol = uidropdown(app.uipanel2);
            app.popupSol.Items = {};
            app.popupSol.ValueChangedFcn = createCallbackFcn(app, @popupSol_Callback, true);
            app.popupSol.Tag = 'popupSol';
            app.popupSol.Value = {};
            app.uipanel5 = uipanel(app.figure1);
            app.uipanel5.Title = 'Jacobian & Manipulability';
            app.uipanel5.Tag = 'uipanel5';
            app.editCond = uieditfield(app.uipanel5, 'text');
            app.editCond.Tag = 'editCond';
            app.editCond.Value = 'cond(J)';
            app.textCond = uilabel(app.uipanel5);
            app.textCond.Tag = 'textCond';
            app.textCond.Text = 'cond(J) =';
            app.editManip = uieditfield(app.uipanel5, 'text');
            app.editManip.Tag = 'editManip';
            app.editManip.Value = 'm';
            app.textManip = uilabel(app.uipanel5);
            app.textManip.Tag = 'textManip';
            app.textManip.Text = 'Manipulability =';
            app.editRankJ = uieditfield(app.uipanel5, 'text');
            app.editRankJ.Tag = 'editRankJ';
            app.editRankJ.Value = 'rank(J)';
            app.textRankJ = uilabel(app.uipanel5);
            app.textRankJ.Tag = 'textRankJ';
            app.textRankJ.Text = 'rank(J) =';
            app.checkEllipseV = uicheckbox(app.uipanel5);
            app.checkEllipseV.ValueChangedFcn = createCallbackFcn(app, @checkEllipseV_Callback, true);
            app.checkEllipseV.Tag = 'checkEllipseV';
            app.checkEllipseV.Text = 'Ellipse of Translation ';
            app.checkEllipseW = uicheckbox(app.uipanel5);
            app.checkEllipseW.ValueChangedFcn = createCallbackFcn(app, @checkEllipseW_Callback, true);
            app.checkEllipseW.Tag = 'checkEllipseW';
            app.checkEllipseW.Text = 'Ellipse of Rotation';
            app.textJsingu = uilabel(app.uipanel5);
            app.textJsingu.Tag = 'textJsingu';
            app.textJsingu.Text = 'Joint Singularity';
            app.textJsinguValue = uilabel(app.uipanel5);
            app.textJsinguValue.Tag = 'textJsinguValue';
            app.textJsinguValue.Text = 'jsingu(J)';
            app.buttonWorkspace = uibutton(app.figure1, 'push');
            app.buttonWorkspace.ButtonPushedFcn = createCallbackFcn(app, @buttonWorkspace_Callback, true);
            app.buttonWorkspace.Tag = 'buttonWorkspace';
            app.buttonWorkspace.Text = 'WORKSPACE';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appRVC_App_2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)appRVC_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end